classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
        Button    matlab.ui.control.Button
        UIAxes7   matlab.ui.control.UIAxes
        UIAxes6   matlab.ui.control.UIAxes
        UIAxes5   matlab.ui.control.UIAxes
        UIAxes4   matlab.ui.control.UIAxes
        UIAxes3   matlab.ui.control.UIAxes
        UIAxes1   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
                 global audioData;

[filename, filepath] = uigetfile({'*.wav;*.mp3;*.ogg;*.m4a;'}, '选择音频文件');

if isequal(filename, 0) || isequal(filepath, 0)
    errordlg('没有选中文件，请重新选择', '出错');
    return;
end

fullpathname = strcat(filepath, filename);

% 读取音频文件
[audioData, sampleRate] = audioread(fullpathname);

% 绘制波形图
t = (0:length(audioData) - 1) / sampleRate; % 时间向量，单位为秒
plot(app.UIAxes1, t, audioData);

% 设置UIAxes1的标签
xlabel(app.UIAxes1, '时间 (秒)');
ylabel(app.UIAxes1, '幅度');
title(app.UIAxes1, '音频波形图');

% 设置预加重系数
alpha = 0.97;
% 预加重
x_preemphasized = zeros(size(audioData));
x_preemphasized(1) = audioData(1);
for n = 2:length(audioData)
    x_preemphasized(n) = audioData(n) - alpha * audioData(n-1);
end


win_length = 320;
frame_shift = 160; % 设置帧移
frames = enframe2(x_preemphasized, win_length, frame_shift,'rectangle');

% frames 现在包含了分帧后的音频信号 
plot(app.UIAxes3, frames);


zcr=zcro(frames);             %求过零率  


frames2=frames.^2;            %求短时能量
energy=sum(frames2,2);

energy_threshold = 0.0002; % 能量门限，根据需求调整
zcr_threshold = 150; % 过零率门限，根据需求调整
% 端点检测（综合利用能量和过零率）
vad_output = (energy > energy_threshold);
t_frames = (0:length(vad_output)-1) * win_length / sampleRate;
plot(app.UIAxes6,t_frames, vad_output);
title(app.UIAxes6,'短时能量VAD结果');
xlabel(app.UIAxes6,'时间（秒）');
ylabel(app.UIAxes6,'VAD输出');


% 端点检测（综合利用能量和过零率）
vad_output2 =  (zcr > zcr_threshold);
t_frames = (0:length(vad_output)-1) * win_length / sampleRate;
plot(app.UIAxes7,t_frames, vad_output2);
title(app.UIAxes7,'过零率VAD结果');
xlabel(app.UIAxes7,'时间（秒）');
ylabel(app.UIAxes7,'VAD输出');


fft_size = 1024; % FFT大小，可以根据需求调整
spectrum = abs(fft(frames, fft_size, 2));
% 绘制频谱图
plot(app.UIAxes4,spectrum);
title(app.UIAxes4,'频谱图');
xlabel(app.UIAxes4,'频率（Hz）');
ylabel(app.UIAxes4,'幅度');


melFilters = melfilter(sampleRate,fft_size,24);
mel_spectrum = spectrum*melFilters';
plot(app.UIAxes5,mel_spectrum);
title(app.UIAxes5,'梅尔滤波后频谱图');
xlabel(app.UIAxes5,'频率（Hz）');
ylabel(app.UIAxes5,'幅度');

mel_spectrum_log = log(mel_spectrum);
num_mfcc_coeffs = 13; % 常用的MFCC系数数量
mfcc_coeffs = dct(mel_spectrum_log, 3);
mfcc_coeffs = mfcc_coeffs(:, 1:num_mfcc_coeffs); % 保留前num_mfcc_coeffs个系数
% 提取列 1 至 11 的数据
selectedColumns = mfcc_coeffs(:, 1:11);

% 将数据显示在表格中
app.UITable.Data = selectedColumns;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '预处理加窗分帧波形')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择音频文件';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end